classdef CensusLensApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DistrictTable            matlab.ui.control.Table
        CENSUSLENSLabel          matlab.ui.control.Label
        CensusObservationsPanel  matlab.ui.container.Panel
        ObservationsValueLabel   matlab.ui.control.Label
        AverageChildSharePanel   matlab.ui.container.Panel
        ChildShareValueLabel     matlab.ui.control.Label
        ChildrenAged06Panel      matlab.ui.container.Panel
        ChildrenValueLabel       matlab.ui.control.Label
        TotalPopulationPanel     matlab.ui.container.Panel
        PopulationValueLabel     matlab.ui.control.Label
        PopulationAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load census data
projectFolder = fileparts(which("CensusLensApp"));

if isempty(projectFolder)
    projectFolder = pwd;
end

processedFile = fullfile(projectFolder, ...
    "data", "processed", "census_processed.mat");

resourceFile = fullfile(projectFolder, ...
    "results", "forecasting", "resource_demand.mat");

% Load processed census data
data = load(processedFile);

% Load resource demand data
resource = load(resourceFile);

T = data.Tclean;
resourceTable = resource.resourceTable;

% Calculate dashboard statistics
totalPopulation = sum(T.TOT_P, "omitnan");
totalChildren = sum(T.P_06, "omitnan");
averageChildShare = mean(T.P_06 ./ T.TOT_P, "omitnan");
numberOfObservations = height(T);

% Update statistic cards
app.PopulationValueLabel.Text = ...
    sprintf("%.2f billion", totalPopulation / 1e9);

app.ChildrenValueLabel.Text = ...
    sprintf("%.2f million", totalChildren / 1e6);

app.ChildShareValueLabel.Text = ...
    sprintf("%.2f%%", averageChildShare * 100);

app.ObservationsValueLabel.Text = ...
    sprintf("%d", numberOfObservations);

% Find the ten most populous observations
[~, idx] = sort(T.TOT_P, "descend");
topCount = min(10, height(T));
topIdx = idx(1:topCount);

% Get district names
districtNames = string(data.regionNames(topIdx));

% Draw population chart
bar(app.PopulationAxes, T.TOT_P(topIdx));

app.PopulationAxes.XTick = 1:topCount;
app.PopulationAxes.XTickLabel = districtNames;
app.PopulationAxes.XTickLabelRotation = 45;
app.PopulationAxes.Title.String = "Top 10 by Population";
app.PopulationAxes.XLabel.String = "District";
app.PopulationAxes.YLabel.String = "Population";
app.PopulationAxes.XAxis.FontSize = 9;

districtTable = table( ...
    districtNames, ...
    T.TOT_P(topIdx), ...
    T.P_06(topIdx), ...
    'VariableNames', ...
    {'District', 'TotalPopulation', 'ChildPopulation'});

app.DistrictTable.Data = districtTable;
app.DistrictTable.ColumnName = ...
    {'District', 'Total Population', 'Children Aged 0-6'};

disp("CensusLens dashboard data loaded successfully.");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PopulationAxes = uiaxes(app.UIFigure);
            title(app.PopulationAxes, 'PopulationAxes')
            xlabel(app.PopulationAxes, 'X')
            ylabel(app.PopulationAxes, 'Y')
            zlabel(app.PopulationAxes, 'Z')
            app.TotalPopulationPanel = uipanel(app.UIFigure);
            app.TotalPopulationPanel.Title = 'Total Population';
            app.PopulationValueLabel = uilabel(app.TotalPopulationPanel);
            app.PopulationValueLabel.Text = 'PopulationValueLabel';
            app.ChildrenAged06Panel = uipanel(app.UIFigure);
            app.ChildrenAged06Panel.Title = 'Children Aged 0–6';
            app.ChildrenValueLabel = uilabel(app.ChildrenAged06Panel);
            app.ChildrenValueLabel.Text = 'ChildrenValueLabel';
            app.AverageChildSharePanel = uipanel(app.UIFigure);
            app.AverageChildSharePanel.Title = 'Average Child Share';
            app.ChildShareValueLabel = uilabel(app.AverageChildSharePanel);
            app.ChildShareValueLabel.Text = 'ChildShareValueLabel';
            app.CensusObservationsPanel = uipanel(app.UIFigure);
            app.CensusObservationsPanel.Title = 'Census Observations';
            app.ObservationsValueLabel = uilabel(app.CensusObservationsPanel);
            app.ObservationsValueLabel.Text = 'ObservationsValueLabel';
            app.CENSUSLENSLabel = uilabel(app.UIFigure);
            app.CENSUSLENSLabel.Text = 'CENSUSLENS';
            app.DistrictTable = uitable(app.UIFigure);
            app.DistrictTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DistrictTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CensusLensApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end